classdef app_v1_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ErrorAnalysisPanel             matlab.ui.container.Panel
        V1PathEditField                matlab.ui.control.EditField
        Label_9                        matlab.ui.control.Label
        V0PathEditField                matlab.ui.control.EditField
        Label_8                        matlab.ui.control.Label
        DescriptionEALabel             matlab.ui.control.Label
        V1StatusLabel                  matlab.ui.control.Label
        V0StatusLabel                  matlab.ui.control.Label
        V0Lamp                         matlab.ui.control.Lamp
        V1Lamp                         matlab.ui.control.Lamp
        RunErrorAnalysisButton         matlab.ui.control.Button
        SelectV1CSVButton              matlab.ui.control.Button
        SelectV0CSVButton              matlab.ui.control.Button
        BackToMainFromAnalysisButton   matlab.ui.control.Button
        AxesV1                         matlab.ui.control.UIAxes
        AxesV0                         matlab.ui.control.UIAxes
        ConditionalPanel               matlab.ui.container.Panel
        TrainCSVPathEditField          matlab.ui.control.EditField
        Label_12                       matlab.ui.control.Label
        ExcelRefPathEditField          matlab.ui.control.EditField
        Label_11                       matlab.ui.control.Label
        ExcelButton                    matlab.ui.control.Button
        CSVLampLabel                   matlab.ui.control.Label
        CSVLamp                        matlab.ui.control.Lamp
        BatchSizeEditField             matlab.ui.control.NumericEditField
        MiniBatchSizeEditFieldLabel    matlab.ui.control.Label
        LREditField                    matlab.ui.control.NumericEditField
        LearnRateEditFieldLabel        matlab.ui.control.Label
        EpochsEditField                matlab.ui.control.NumericEditField
        MaxEpochsEditFieldLabel        matlab.ui.control.Label
        FluxMinEditField               matlab.ui.control.NumericEditField
        Label_6                        matlab.ui.control.Label
        FluxMaxEditField               matlab.ui.control.NumericEditField
        Label_5                        matlab.ui.control.Label
        FluxStepEditField              matlab.ui.control.NumericEditField
        Label_4                        matlab.ui.control.Label
        TorqueStepEditField            matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
        TorqueMaxEditField             matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        TorqueMinEditField             matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        SelectCSVButton                matlab.ui.control.Button
        BackToMainFromConditionButton  matlab.ui.control.Button
        MotorPanel                     matlab.ui.container.Panel
        LdLqPathEditField              matlab.ui.control.EditField
        Label_10                       matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        LampLabel                      matlab.ui.control.Label
        VdcEditField                   matlab.ui.control.NumericEditField
        VdcEditFieldLabel              matlab.ui.control.Label
        ImaxEditField                  matlab.ui.control.NumericEditField
        ImaxLabel                      matlab.ui.control.Label
        FluxEditField                  matlab.ui.control.NumericEditField
        FluxLabel                      matlab.ui.control.Label
        LqEditField                    matlab.ui.control.NumericEditField
        qLqEditFieldLabel              matlab.ui.control.Label
        LdEditField                    matlab.ui.control.NumericEditField
        dLdLabel                       matlab.ui.control.Label
        RsEditField                    matlab.ui.control.NumericEditField
        RsEditFieldLabel               matlab.ui.control.Label
        pEditField                     matlab.ui.control.NumericEditField
        pEditFieldLabel                matlab.ui.control.Label
        LoadLUTButton                  matlab.ui.control.Button
        BackToMainFromMotorButton      matlab.ui.control.Button
        OutputPanel                    matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        DataDistTab                    matlab.ui.container.Tab
        DescriptionDataLabel           matlab.ui.control.Label
        StatusLabel                    matlab.ui.control.Label
        GenerateLUTButton              matlab.ui.control.Button
        ReportTextArea                 matlab.ui.control.TextArea
        Label_7                        matlab.ui.control.Label
        TorqueHistAxes                 matlab.ui.control.UIAxes
        FluxHistAxes                   matlab.ui.control.UIAxes
        TrainingTrendTab               matlab.ui.container.Tab
        DescriptionPredictLabel        matlab.ui.control.Label
        DescriptionLossLabel           matlab.ui.control.Label
        ResidualIqAxes                 matlab.ui.control.UIAxes
        ResidualIdAxes                 matlab.ui.control.UIAxes
        DirectIqAxes                   matlab.ui.control.UIAxes
        DirectIdAxes                   matlab.ui.control.UIAxes
        ResidualLossAxes               matlab.ui.control.UIAxes
        DirectLossAxes                 matlab.ui.control.UIAxes
        ResultOutputTab                matlab.ui.container.Tab
        DescriptionLUTLabel            matlab.ui.control.Label
        DescriptionMTPALabel           matlab.ui.control.Label
        IqSurfaceAxes                  matlab.ui.control.UIAxes
        IdSurfaceAxes                  matlab.ui.control.UIAxes
        IdIqPlaneResidualAxes          matlab.ui.control.UIAxes
        IdIqPlaneDirectAxes            matlab.ui.control.UIAxes
        BackToMainFromOutputButton     matlab.ui.control.Button
        MainMenuPanel                  matlab.ui.container.Panel
        GoToErrorAnalysisButton        matlab.ui.control.Button
        AIappv14Label                  matlab.ui.control.Label
        GoToOutputButton               matlab.ui.control.Button
        GoToConditionButton            matlab.ui.control.Button
        GoToMotorButton                matlab.ui.control.Button
    end

    properties (Access = private)
        % --- AI 模型 (單一直接模型: (T,Flux) -> (Id,Iq)) ---
        net_direct    % 訓練完成的 NN
        Y_mean_dir    % 輸出正規化均值 [Id, Iq]
        Y_std_dir     % 輸出正規化標準差 [Id, Iq]

        % --- 檔案路徑 ---
        trainCSVPath = '20250520_500rpm_13000rpm_effMap_Report.csv'
        v0CSVPath = 'V0_500rpm_13000rpm_effMap_Report.csv';
        v1CSVPath = 'V1_500rpm_13000rpm_effMap_Report.csv';

        % --- 馬達參數頁狀態 (v1.4 產表已不使用電感表, 僅供介面顯示) ---
        motor
    end
    methods (Access = private)

        % =========================================================
        % 欄位自動偵測: 從 CSV 標題找出 扭矩/轉速/Id/Iq 欄
        % (電流優先取 Feedback 欄; 找不到必要欄位時直接報錯)
        % =========================================================
        function [trq_col, spd_col, id_col, iq_col] = autoDetectColumns(~, dataTable)
            varNames = dataTable.Properties.VariableNames;
            spd_idx = find(~cellfun(@isempty, regexpi(varNames, 'speed|rpm|spd|^n$')), 1);
            trq_idx = find(~cellfun(@isempty, regexpi(varNames, 'T_dem_E|torque|trq|nm|^t$')), 1);

            id_matches = find(~cellfun(@isempty, regexpi(varNames, 'daxis|id|d_axis|curr.*d|id_a')));
            id_fbk = id_matches(~cellfun(@isempty, regexpi(varNames(id_matches), 'fbk|feedback|actual')));
            if ~isempty(id_fbk), id_idx = id_fbk(1); else, id_idx = id_matches(1); end

            iq_matches = find(~cellfun(@isempty, regexpi(varNames, 'qaxis|iq|q_axis|curr.*q|iq_a')));
            iq_fbk = iq_matches(~cellfun(@isempty, regexpi(varNames(iq_matches), 'fbk|feedback|actual')));
            if ~isempty(iq_fbk), iq_idx = iq_fbk(1); else, iq_idx = iq_matches(1); end

            if isempty(spd_idx) || isempty(trq_idx) || isempty(id_idx) || isempty(iq_idx)
                error('匯入失敗！CSV 檔中找不到標準的 Speed/Torque/Id/Iq 欄位，請確認欄位標題。');
            end
            spd_col = varNames{spd_idx};
            trq_col = varNames{trq_idx};
            id_col  = varNames{id_idx};
            iq_col  = varNames{iq_idx};
        end

        % =========================================================
        % UI 重置: 清空狀態文字與所有輸出圖
        % =========================================================
        function resetOutputs(app)
            app.StatusLabel.Text = '等待執行...';
            app.ReportTextArea.Value = '';
            axesList = [app.FluxHistAxes,       app.TorqueHistAxes, ...
                app.DirectLossAxes,     app.ResidualLossAxes, ...
                app.DirectIdAxes,       app.DirectIqAxes, ...
                app.ResidualIdAxes,     app.ResidualIqAxes, ...
                app.IdSurfaceAxes,      app.IqSurfaceAxes, ...
                app.IdIqPlaneDirectAxes, app.IdIqPlaneResidualAxes];
            for ax = axesList
                cla(ax, 'reset');
            end
        end

        % =========================================================
        % 散佈圖: 預測 vs 實驗 + 45 度參考線
        % =========================================================
        function plot_scatter_fit(~, ax, true_val, pred_val, title_str, dot_color)
            plot(ax, true_val, pred_val, '.', 'Color', dot_color, 'MarkerSize', 8);
            hold(ax, 'on');
            ref_line = [min(true_val), max(true_val)];
            plot(ax, ref_line, ref_line, 'r--', 'LineWidth', 2);
            hold(ax, 'off'); grid(ax, 'on');
            title(ax, title_str); xlabel(ax, 'True Value'); ylabel(ax, 'Predicted Value');
        end

        % =========================================================
        % PAVA 單調化 (pool adjacent violators)
        % nonincreasing: 強制序列不遞增; nondecreasing: 強制不遞減(通用包裝)
        % =========================================================
        function y_mono = pava_nonincreasing(~, y)
            y = y(:)'; n = numel(y);
            level = zeros(1,n); weight = zeros(1,n); cnt = 0;
            for k = 1:n
                cnt = cnt + 1; level(cnt) = y(k); weight(cnt) = 1;
                while cnt > 1 && level(cnt) > level(cnt-1)
                    level(cnt-1)  = (level(cnt-1)*weight(cnt-1) + level(cnt)*weight(cnt)) / (weight(cnt-1)+weight(cnt));
                    weight(cnt-1) = weight(cnt-1) + weight(cnt);
                    cnt = cnt - 1;
                end
            end
            y_mono = zeros(1,n); p = 1;
            for b = 1:cnt
                y_mono(p:p+weight(b)-1) = level(b); p = p + weight(b);
            end
        end

        function y_out = pava_nondecreasing(app, y_in)
            % 保持輸入形狀 (列或欄向量皆可)
            y_out = reshape(-app.pava_nonincreasing(-y_in), size(y_in));
        end

        % =========================================================
        % 表格物理約束 (校準每輪後重新套用, 順序不可改):
        %   1) 符號: Id<=0, Iq>=0   2) 零扭矩列 Iq=0 (Id 保留弱磁值)
        %   3) 單調性: Id 隨磁通不減 / Iq 隨扭矩不減   4) |I|<=Imax 限幅
        % =========================================================
        function [Id_t, Iq_t] = applyTableConstraints(app, Id_t, Iq_t, torque_vec, Imax)
            Id_t(Id_t > 0) = 0;
            Iq_t(Iq_t < 0) = 0;
            Iq_t(torque_vec <= 0.2, :) = 0;
            for i = 1:size(Id_t, 1)
                Id_t(i,:) = app.pava_nondecreasing(Id_t(i,:));
            end
            for j = 1:size(Iq_t, 2)
                Iq_t(:,j) = app.pava_nondecreasing(Iq_t(:,j));
            end
            Im = sqrt(Id_t.^2 + Iq_t.^2);
            ov = Im > Imax;
            Id_t(ov) = Id_t(ov) .* (Imax ./ Im(ov));
            Iq_t(ov) = Iq_t(ov) .* (Imax ./ Im(ov));
        end

        % =========================================================
        % 讀取 ZEPT 參考表並內插到產表網格 (讀不到時 ref_ok=false)
        % =========================================================
        function [Id_ref, Iq_ref, ref_ok] = readReferenceTable(app, TG, FG)
            Id_ref = []; Iq_ref = []; ref_ok = false;
            ref_path = app.ExcelRefPathEditField.Value;
            if isempty(ref_path) || ~isfile(ref_path), return; end
            try
                sheets = sheetnames(ref_path);
                id_sh = sheets{find(~cellfun(@isempty, regexpi(sheets, 'id')), 1)};
                iq_sh = sheets{find(~cellfun(@isempty, regexpi(sheets, 'iq')), 1)};
                raw_id = readmatrix(ref_path, 'Sheet', id_sh);
                raw_iq = readmatrix(ref_path, 'Sheet', iq_sh);
                % 第 1 列 = 磁通軸, 第 1 欄 = 扭矩軸, 其餘 = 電流矩陣
                fr = raw_id(1, 2:end); vf = find(~isnan(fr));
                tr = raw_id(2:end, 1); vt = find(~isnan(tr));
                flux_ref = fr(vf); trq_ref = tr(vt)';
                Id_ref_m = raw_id(1+vt, 1+vf); Iq_ref_m = raw_iq(1+vt, 1+vf);
                [flux_ref, fo] = sort(flux_ref); Id_ref_m = Id_ref_m(:,fo); Iq_ref_m = Iq_ref_m(:,fo);
                [trq_ref, to]  = sort(trq_ref);  Id_ref_m = Id_ref_m(to,:); Iq_ref_m = Iq_ref_m(to,:);
                FId = griddedInterpolant({trq_ref, flux_ref}, Id_ref_m, 'linear', 'nearest');
                FIq = griddedInterpolant({trq_ref, flux_ref}, Iq_ref_m, 'linear', 'nearest');
                Id_ref = FId(TG, FG); Iq_ref = FIq(TG, FG);
                ref_ok = true;
            catch
                ref_ok = false;
            end
        end

        % =========================================================
        % 誤差熱力圖繪製 (V0/V1 共用; 色階固定 0~15 Nm)
        % =========================================================
        function drawErrorHeatmap(~, ax, Z, xLabels, yLabels, title_str)
            [Ny, Nx] = size(Z);
            max_err = 15;
            cla(ax, 'reset');
            h_img = imagesc(ax, 1:Nx, 1:Ny, Z);
            set(ax, 'YDir', 'normal', 'Color', [0.94 0.94 0.94]);
            set(h_img, 'AlphaData', ~isnan(Z));
            colormap(ax, jet);
            clim(ax, [0, max_err]);
            for i = 1:Ny
                for j = 1:Nx
                    val = Z(i, j);
                    if ~isnan(val)
                        % 深藍區與深紅區改白字, 其餘黑字
                        t_col = 'k'; if val < max_err*0.2 || val >= 12, t_col = 'w'; end
                        text(ax, j, i, sprintf('%.1f', val), ...
                            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
                            'Color', t_col, 'FontSize', 9, 'FontWeight', 'bold');
                    end
                end
            end
            set(ax, 'XTick', 1:Nx, 'XTickLabel', xLabels, 'YTick', 1:Ny, 'YTickLabel', yLabels);
            xlabel(ax, '轉速 (RPM)'); ylabel(ax, '扭矩命令 (Nm)');
            title(ax, title_str);
            cb = colorbar(ax); cb.Label.String = '絕對誤差 (Nm)';
            hold(ax, 'on');
            for x_edge = 0.5:(Nx+0.5), plot(ax, [x_edge x_edge], [0.5, Ny+0.5], 'Color', [0 0 0 0.15]); end
            for y_edge = 0.5:(Ny+0.5), plot(ax, [0.5, Nx+0.5], [y_edge y_edge], 'Color', [0 0 0 0.15]); end
            hold(ax, 'off');
            ax.XLim = [0.5, Nx+0.5]; ax.YLim = [0.5, Ny+0.5];
        end

        % =========================================================
        % 誤差分析頁: 比對 V0/V1 兩次台架的扭矩誤差, 畫成熱力圖
        % =========================================================
        function runTorqueErrorAnalysis(app)
            try
                if ~isfile(app.v0CSVPath) || ~isfile(app.v1CSVPath)
                    uialert(app.UIFigure, '請先點擊選取有效的 V0 與 V1 CSV 數據檔案！', '提示', 'Icon', 'warning');
                    return;
                end

                % --- 讀檔 (V1 第 2 列為單位列, 跳過) ---
                opts_v0 = detectImportOptions(app.v0CSVPath, 'VariableNamingRule', 'preserve');
                old_data = readtable(app.v0CSVPath, opts_v0);
                opts_v1 = detectImportOptions(app.v1CSVPath, 'VariableNamingRule', 'preserve');
                opts_v1.DataLines = [3, Inf];
                new_data = readtable(app.v1CSVPath, opts_v1);

                % --- 欄位提取 (torq error 拼字防呆) + 轉數值 ---
                to_num = @(v) subsref({v, str2double(v)}, substruct('{}', {1 + iscell(v)}));
                speed_old   = to_num(old_data.SPEED);
                trq_cmd_old = to_num(old_data.T_dem_E);
                speed_new   = to_num(new_data.SPEED);
                trq_cmd_new = to_num(new_data.T_dem_E);
                if ismember('torqe error', old_data.Properties.VariableNames)
                    err_old = abs(to_num(old_data.("torqe error")));
                else
                    err_old = abs(to_num(old_data.("torq error")));
                end
                err_new = abs(to_num(new_data.("torq error")));

                % --- 濾 NaN ---
                v_old = ~isnan(speed_old) & ~isnan(trq_cmd_old) & ~isnan(err_old);
                speed_old = speed_old(v_old); trq_cmd_old = trq_cmd_old(v_old); err_old = err_old(v_old);
                v_new = ~isnan(speed_new) & ~isnan(trq_cmd_new) & ~isnan(err_new);
                speed_new = speed_new(v_new); trq_cmd_new = trq_cmd_new(v_new); err_new = err_new(v_new);

                % --- 工作點對齊到 500rpm x 2Nm 網格, 取兩者交集 ---
                op_old_grid = [round(speed_old/500)*500, round(trq_cmd_old/2)*2];
                op_new_grid = [round(speed_new/500)*500, round(trq_cmd_new/2)*2];
                common_ops = intersect(unique(op_old_grid, 'rows'), unique(op_new_grid, 'rows'), 'rows');
                common_ops = common_ops(common_ops(:,1) >= 1000 & common_ops(:,2) >= 20, :);
                if isempty(common_ops)
                    uialert(app.UIFigure, '過濾後沒有可比較的共同操作點！', '警告', 'Icon', 'warning');
                    return;
                end

                % --- 每個交集點的平均誤差 -> 矩陣 Z (扭矩 x 轉速) ---
                X_unique = unique(common_ops(:,1));
                Y_unique = unique(common_ops(:,2));
                Z_old = nan(numel(Y_unique), numel(X_unique));
                Z_new = Z_old;
                for i = 1:size(common_ops, 1)
                    op = common_ops(i, :);
                    row = find(Y_unique == op(2)); col = find(X_unique == op(1));
                    m_old = op_old_grid(:,1) == op(1) & op_old_grid(:,2) == op(2);
                    m_new = op_new_grid(:,1) == op(1) & op_new_grid(:,2) == op(2);
                    Z_old(row, col) = mean(err_old(m_old));
                    Z_new(row, col) = mean(err_new(m_new));
                end

                app.drawErrorHeatmap(app.AxesV0, Z_old, X_unique, Y_unique, '舊版查表 (V0) - 絕對誤差 (Nm)');
                app.drawErrorHeatmap(app.AxesV1, Z_new, X_unique, Y_unique, '新版查表 (V1) - 絕對誤差 (Nm)');
                uialert(app.UIFigure, '誤差熱力圖繪製完成！', '分析成功', 'Icon', 'success');
            catch ME
                uialert(app.UIFigure, ['分析失敗: ', ME.message], '錯誤', 'Icon', 'error');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.resetOutputs();
            app.MotorPanel.Visible = 'off';
            app.ConditionalPanel.Visible = 'off';
            app.OutputPanel.Visible = 'off';
            app.MainMenuPanel.Visible = 'on';
        end

        % Button pushed function: BackToMainFromMotorButton
        function BackToMainFromMotorButtonPushed(app, event)
            app.MainMenuPanel.Visible = 'on';
            app.MotorPanel.Visible = 'off';
        end

        % Button pushed function: GoToMotorButton
        function GoToMotorButtonPushed(app, event)
            app.MainMenuPanel.Visible = 'off';
            app.MotorPanel.Visible = 'on';
        end

        % Button pushed function: GoToConditionButton
        function GoToConditionButtonPushed(app, event)
            app.MainMenuPanel.Visible = 'off';
            app.ConditionalPanel.Visible = 'on';
        end

        % Button pushed function: GoToOutputButton
        function GoToOutputButtonPushed(app, event)
            app.MainMenuPanel.Visible = 'off';
            app.OutputPanel.Visible = 'on'; 
        end

        % Button pushed function: BackToMainFromConditionButton
        function BackToMainFromConditionButtonPushed(app, event)
            app.MainMenuPanel.Visible = 'on';
            app.ConditionalPanel.Visible = 'off';
        end

        % Button pushed function: BackToMainFromOutputButton
        function BackToMainFromOutputButtonPushed(app, event)
            app.MainMenuPanel.Visible = 'on';
            app.OutputPanel.Visible = 'off';
        end

        % Button pushed function: LoadLUTButton
        function LoadLUTButtonPushed(app, event)
            Ld_fixed = app.LdEditField.Value;
            Lq_fixed = app.LqEditField.Value;
            imax_val = app.ImaxEditField.Value;

            [filename, pathname] = uigetfile('*.xlsx', '請選擇包含 Ld 與 Lq 分頁的 Excel 檔案');
            if isequal(filename, 0), return; end

            try
                filepath = fullfile(pathname, filename);

                % 1. 先讀取 Excel 數據矩陣
                Ld_mat = flipud(readmatrix(filepath, 'Sheet', 'Ld_LUT')');
                Lq_mat = flipud(readmatrix(filepath, 'Sheet', 'Lq_LUT')');

                % 2. ⭐ 動態取得 Excel 矩陣的實際維度 (列數 n_id, 行數 n_iq)
                [n_id, n_iq] = size(Ld_mat);

                % 3. ⭐ 依據實際尺寸動態生成網格，確保維度 100% 完全相符
                id_axis = linspace(-imax_val, 0, n_id);
                iq_axis = linspace(0, imax_val, n_iq);
                [ID_grid, IQ_grid] = ndgrid(id_axis, iq_axis);

                % 4. 建立 Gridded Interpolant 物件
                Ld_interp_obj = griddedInterpolant(ID_grid, IQ_grid, Ld_mat, 'linear', 'nearest');
                Lq_interp_obj = griddedInterpolant(ID_grid, IQ_grid, Lq_mat, 'linear', 'nearest');

                app.motor.Ld_interp = @(id, iq) Ld_interp_obj(id, iq);
                app.motor.Lq_interp = @(id, iq) Lq_interp_obj(id, iq);
                app.motor.LUT_filename = filename;

                % 更新介面顯示路徑與燈號
                if isprop(app, 'LdLqPathEditField')
                    app.LdLqPathEditField.Value = filepath;
                end
                app.Lamp.Color = [0, 1, 0];
                app.LampLabel.Text = '載入成功！已套用動態電感';
                app.LampLabel.FontColor = [0, 0.8, 0];
                uialert(app.UIFigure, '動態 Ld / Lq 電感查表已成功載入！', '載入成功', 'Icon', 'success');

            catch ME
                % 載入失敗時降級使用固定電感
                app.motor.Ld_interp = @(id, iq) Ld_fixed;
                app.motor.Lq_interp = @(id, iq) Lq_fixed;
                app.motor.LUT_filename = '固定電感';

                if isprop(app, 'LdLqPathEditField')
                    app.LdLqPathEditField.Value = '載入失敗，使用固定電感';
                end
                app.Lamp.Color = [1, 0, 0];
                app.LampLabel.Text = '載入失敗，使用固定電感';
                app.LampLabel.FontColor = [1, 0, 0];

                error_msg = ['無法解析電感表格式，請確認 Excel 分頁名稱是否為 "Ld_LUT" 與 "Lq_LUT"！' newline '詳細錯誤: ' ME.message];
                uialert(app.UIFigure, error_msg, '載入失敗', 'Icon', 'error');
            end
        end

        % Button pushed function: GenerateLUTButton
        function GenerateLUTButtonPushed(app, event)
            app.resetOutputs();
            app.StatusLabel.Text = '🔄 1/3 讀取數據與 AI 訓練中...';
            app.StatusLabel.FontColor = [0.9, 0.5, 0];
            drawnow;
            try
                %% ===== 1. 介面參數 =====
                max_epochs = app.EpochsEditField.Value;
                init_lr    = app.LREditField.Value;
                batch_size = app.BatchSizeEditField.Value;
                p_pairs    = app.pEditField.Value;
                Imax       = app.ImaxEditField.Value;

                %% ===== 2. 讀取實驗數據 =====
                % 訓練座標 = (扭矩, 磁通命令): Flux 欄就是控制器查表的索引。
                % 若含動力計實測扭矩欄 (TORQUE), 以實測值取代命令值作為扭矩標籤,
                % 使表格「查 T 得到的電流」實際產生 T, 從根本消除扭矩命令誤差。
                if ~isfile(app.trainCSVPath)
                    error('找不到訓練數據檔案：%s，請點擊「選擇訓練資料 (CSV)」重新選取。', app.trainCSVPath);
                end
                data = readtable(app.trainCSVPath);
                [trq_col, spd_col, id_col, iq_col] = app.autoDetectColumns(data);
                varNames = data.Properties.VariableNames;
                flux_idx = find(~cellfun(@isempty, regexpi(varNames, 'flux|psi|lambda')), 1);
                if isempty(flux_idx)
                    error('CSV 中找不到磁通命令欄位 (Flux)！產表必須使用控制器的磁通命令欄。');
                end
                T_e = data.(trq_col);
                S_e = data.(spd_col);
                if ismember('TORQUE', varNames)          % 實測扭矩優先 (缺值列保留命令值)
                    T_meas = data.TORQUE;
                    has_m = ~isnan(T_meas);
                    T_e(has_m) = max(T_meas(has_m), 0);
                end
                F_e  = data.(varNames{flux_idx});
                Id_e = data.(id_col);
                Iq_e = data.(iq_col);

                % 有效樣本: 無缺值、非負扭矩、電流量在 1.1*Imax 內
                valid = ~isnan(T_e) & ~isnan(F_e) & ~isnan(Id_e) & ~isnan(Iq_e) & ...
                    T_e >= 0 & sqrt(Id_e.^2 + Iq_e.^2) <= Imax*1.1;
                T_e = T_e(valid); S_e = S_e(valid); F_e = F_e(valid);
                Id_e = Id_e(valid); Iq_e = Iq_e(valid);

                % 完全重複的 (T, Flux) 工作點取平均
                key = [T_e, round(F_e, 4)];
                [ukey, ~, gidx] = unique(key, 'rows');
                T_u  = ukey(:,1);
                F_u  = accumarray(gidx, F_e,  [], @mean);
                Id_u = accumarray(gidx, Id_e, [], @mean);
                Iq_u = accumarray(gidx, Iq_e, [], @mean);
                S_u  = accumarray(gidx, S_e,  [], @mean);
                n = numel(T_u);
                V_eff = median(F_u .* S_u .* (pi/30) .* p_pairs);   % 實測有效電壓 (僅供報告)

                %% ===== 3. 訓練 NN: (T, Flux) -> (Id, Iq) =====
                X = [T_u, F_u];
                Y = [Id_u, Iq_u];
                app.Y_mean_dir = mean(Y, 1);
                app.Y_std_dir  = std(Y, 0, 1) + 1e-6;
                Y_n = (Y - app.Y_mean_dir) ./ app.Y_std_dir;
                rng(1);                                   % 固定亂數: 結果可重現
                [trainIdx, valIdx] = dividerand(n, 0.85, 0.15, 0.0);
                batch_size = min(batch_size, numel(trainIdx));   % 批次不可大於樣本數
                layers = [
                    featureInputLayer(2, 'Normalization', 'zscore')
                    fullyConnectedLayer(64); leakyReluLayer(0.1)
                    fullyConnectedLayer(32); leakyReluLayer(0.1)
                    fullyConnectedLayer(2); regressionLayer()
                    ];
                options = trainingOptions('adam', ...
                    'MaxEpochs', max_epochs, 'MiniBatchSize', batch_size, ...
                    'Shuffle', 'every-epoch', ...
                    'InitialLearnRate', init_lr, 'L2Regularization', 1e-4, 'GradientThreshold', 1.0, ...
                    'LearnRateSchedule', 'piecewise', ...
                    'LearnRateDropPeriod', max(1, round(max_epochs/3)), 'LearnRateDropFactor', 0.2, ...
                    'ValidationData', {X(valIdx,:), Y_n(valIdx,:)}, 'ValidationFrequency', 10, ...
                    'Plots', 'none', 'Verbose', false);
                [app.net_direct, info] = trainNetwork(X(trainIdx,:), Y_n(trainIdx,:), layers, options);

                %% ===== 4. 在刻意設定的 (T, Flux) 網格上求值 =====
                app.StatusLabel.Text = '🔄 2/3 產生查表與參考表填補...';
                drawnow;
                torque_vec = app.TorqueMinEditField.Value : app.TorqueStepEditField.Value : app.TorqueMaxEditField.Value;
                flux_vec   = app.FluxMinEditField.Value  : app.FluxStepEditField.Value  : app.FluxMaxEditField.Value;
                torque_vec = torque_vec(:)';
                flux_vec   = sort(flux_vec(:)');
                n_t = numel(torque_vec); n_f = numel(flux_vec);
                [FG, TG] = meshgrid(flux_vec, torque_vec);
                Yg = predict(app.net_direct, [TG(:), FG(:)]) .* app.Y_std_dir + app.Y_mean_dir;
                Id_grid = double(reshape(Yg(:,1), n_t, n_f));
                Iq_grid = double(reshape(Yg(:,2), n_t, n_f));

                %% ===== 5. 實驗涵蓋範圍 (每個扭矩列的磁通上下界) =====
                % 界外格不可信任 NN 外插, 交由第 6 步填補
                tolT = max(app.TorqueStepEditField.Value, 10);
                f_lo = nan(n_t,1); f_hi = nan(n_t,1);
                for i = 1:n_t
                    m = abs(T_u - torque_vec(i)) <= tolT;
                    if any(m), f_lo(i) = min(F_u(m)); f_hi(i) = max(F_u(m)); end
                end
                f_lo = fillmissing(f_lo, 'nearest');
                f_hi = fillmissing(f_hi, 'nearest');
                in_support = false(n_t, n_f);
                for i = 1:n_t
                    in_support(i,:) = flux_vec >= f_lo(i)-0.002 & ...
                        (flux_vec <= f_hi(i)+0.002 | f_hi(i) >= flux_vec(end));
                end

                %% ===== 6. 範圍外填補 =====
                % 有參考表: ref + (模型邊界值 - ref 邊界值), 接縫連續 (差值修正)
                % 無參考表: 用邊界值延續 (安全 fallback)
                [Id_ref, Iq_ref, ref_ok] = app.readReferenceTable(TG, FG);
                Id_final = Id_grid; Iq_final = Iq_grid;
                for i = 1:n_t
                    s = in_support(i,:);
                    if ~any(s)
                        if ref_ok, Id_final(i,:) = Id_ref(i,:); Iq_final(i,:) = Iq_ref(i,:); end
                        continue;
                    end
                    j_lo = find(s, 1, 'first'); j_hi = find(s, 1, 'last');
                    lo_c = find(~s & (1:n_f) < j_lo);
                    hi_c = find(~s & (1:n_f) > j_hi);
                    if ref_ok
                        Id_final(i,lo_c) = Id_ref(i,lo_c) + (Id_grid(i,j_lo) - Id_ref(i,j_lo));
                        Iq_final(i,lo_c) = Iq_ref(i,lo_c) + (Iq_grid(i,j_lo) - Iq_ref(i,j_lo));
                        Id_final(i,hi_c) = Id_ref(i,hi_c) + (Id_grid(i,j_hi) - Id_ref(i,j_hi));
                        Iq_final(i,hi_c) = Iq_ref(i,hi_c) + (Iq_grid(i,j_hi) - Iq_ref(i,j_hi));
                    else
                        Id_final(i,lo_c) = Id_grid(i,j_lo); Iq_final(i,lo_c) = Iq_grid(i,j_lo);
                        Id_final(i,hi_c) = Id_grid(i,j_hi); Iq_final(i,hi_c) = Iq_grid(i,j_hi);
                    end
                end

                %% ===== 7. 表格校準 (2 輪) =====
                % 控制器實際做雙線性查表, 因此直接對節點值做帶正則化的最小平方,
                % 讓「查表結果」對實測點誤差最小; 每輪後重套物理約束。
                app.StatusLabel.Text = '🔄 3/3 表格校準與驗證...';
                drawnow;
                if n_t >= 2 && n_f >= 2
                    lambda = 0.5;                 % 正則化: 偏離先驗表 1A 的代價 = 0.5A 資料誤差
                    nNode = n_t * n_f;
                    rows_ = zeros(n*4,1); cols_ = rows_; vals_ = rows_; cnt = 0;
                    for k = 1:n
                        tqk = min(max(T_u(k), torque_vec(1)), torque_vec(end));
                        fqk = min(max(F_u(k), flux_vec(1)),  flux_vec(end));
                        it = find(torque_vec <= tqk, 1, 'last'); it = min(it, n_t-1);
                        jf = find(flux_vec <= fqk, 1, 'last');   jf = min(jf, n_f-1);
                        at = (tqk - torque_vec(it)) / (torque_vec(it+1) - torque_vec(it));
                        af = (fqk - flux_vec(jf))  / (flux_vec(jf+1)  - flux_vec(jf));
                        w4 = [(1-at)*(1-af), (1-at)*af, at*(1-af), at*af];
                        n4 = [sub2ind([n_t n_f], it, jf),   sub2ind([n_t n_f], it, jf+1), ...
                            sub2ind([n_t n_f], it+1, jf), sub2ind([n_t n_f], it+1, jf+1)];
                        for q = 1:4
                            cnt = cnt + 1; rows_(cnt) = k; cols_(cnt) = n4(q); vals_(cnt) = w4(q);
                        end
                    end
                    A = sparse(rows_(1:cnt), cols_(1:cnt), vals_(1:cnt), n, nNode);
                    Areg = [A; lambda*speye(nNode)];
                    for cal_pass = 1:2
                        Id_final = reshape(Areg \ [Id_u; lambda*Id_final(:)], n_t, n_f);
                        Iq_final = reshape(Areg \ [Iq_u; lambda*Iq_final(:)], n_t, n_f);
                        [Id_final, Iq_final] = app.applyTableConstraints(Id_final, Iq_final, torque_vec, Imax);
                    end
                end

                %% ===== 8. 能力包絡延續 =====
                % t_cap(j) = 磁通 <= 此欄時實測達成過的最大扭矩 (cummax 保證隨磁通單調)。
                % 高於能力的列不外插, 直接複製能力上限列的電流。
                t_cap = nan(1, n_f);
                for j = 1:n_f
                    m = F_u <= flux_vec(j) + max(app.FluxStepEditField.Value, 0.004);
                    if any(m), t_cap(j) = max(T_u(m)); end
                end
                t_cap = cummax(fillmissing(t_cap, 'nearest'));
                for j = 1:n_f
                    over_rows = torque_vec > t_cap(j) + 5;
                    if any(over_rows)
                        cap_row = find(torque_vec <= t_cap(j) + 5, 1, 'last');
                        Id_final(over_rows, j) = Id_final(cap_row, j);
                        Iq_final(over_rows, j) = Iq_final(cap_row, j);
                    end
                end

                %% ===== 9. 驗證: 最終表在全部實測工作點查表比對 =====
                F_id_lut = griddedInterpolant({torque_vec, flux_vec}, Id_final, 'linear', 'nearest');
                F_iq_lut = griddedInterpolant({torque_vec, flux_vec}, Iq_final, 'linear', 'nearest');
                tq_all = min(max(T_u, torque_vec(1)), torque_vec(end));
                fq_all = min(max(F_u, flux_vec(1)),  flux_vec(end));
                id_lut = F_id_lut(tq_all, fq_all);
                iq_lut = F_iq_lut(tq_all, fq_all);
                dId = id_lut - Id_u;
                dIq = iq_lut - Iq_u;
                Y_pred_nn = predict(app.net_direct, X) .* app.Y_std_dir + app.Y_mean_dir;   % NN in-sample

                %% ===== 10. 更新 UI =====
                % --- 資料分布 ---
                histogram(app.FluxHistAxes, F_u, 20, 'FaceColor', [0 0.8 0.8], 'EdgeColor', 'w');
                title(app.FluxHistAxes, '磁通命令分布 (控制器 Flux 欄)');
                xlabel(app.FluxHistAxes, 'Flux command (Wb)'); ylabel(app.FluxHistAxes, '資料筆數');
                histogram(app.TorqueHistAxes, T_u, 20, 'FaceColor', [0.8 0 0.8], 'EdgeColor', 'w');
                title(app.TorqueHistAxes, '扭矩資料量分布');
                xlabel(app.TorqueHistAxes, 'Torque (Nm)'); ylabel(app.TorqueHistAxes, '資料筆數');

                % --- Loss 曲線 (灰=各批次訓練損失, 綠=固定驗證集損失) ---
                cla(app.DirectLossAxes);
                semilogy(app.DirectLossAxes, info.TrainingLoss, 'Color', [0.7 0.7 0.7], 'LineWidth', 1.2, 'DisplayName', 'Training Loss');
                hold(app.DirectLossAxes, 'on');
                vl = info.ValidationLoss;
                vmask = ~isnan(vl) & vl > 0;
                if any(vmask)
                    semilogy(app.DirectLossAxes, find(vmask), vl(vmask), 'g-', 'LineWidth', 2.0, 'DisplayName', 'Validation Loss');
                end
                hold(app.DirectLossAxes, 'off'); grid(app.DirectLossAxes, 'on');
                title(app.DirectLossAxes, 'NN Loss (T,Flux)->(Id,Iq)');
                xlabel(app.DirectLossAxes, 'Iteration'); ylabel(app.DirectLossAxes, 'MSE (Log)');
                legend(app.DirectLossAxes, 'TextColor', 'k', 'Color', 'none', 'EdgeColor', 'none', 'Location', 'northeast');

                % --- 模型精度: 訓練集 vs 驗證集 RMSE (安培) ---
                cla(app.ResidualLossAxes);
                rmse_vals = [rms(Y_pred_nn(trainIdx,1) - Id_u(trainIdx)), rms(Y_pred_nn(valIdx,1) - Id_u(valIdx)); ...
                    rms(Y_pred_nn(trainIdx,2) - Iq_u(trainIdx)), rms(Y_pred_nn(valIdx,2) - Iq_u(valIdx))];
                hb = bar(app.ResidualLossAxes, rmse_vals);
                hb(1).FaceColor = [0.3 0.6 0.9]; hb(2).FaceColor = [0.95 0.6 0.2];
                set(app.ResidualLossAxes, 'XTick', 1:2, 'XTickLabel', {'Id', 'Iq'});
                ylabel(app.ResidualLossAxes, '電流誤差 RMSE (A)');
                title(app.ResidualLossAxes, '模型預測精度 (訓練集 vs 驗證集)');
                legend(app.ResidualLossAxes, {'訓練集', '驗證集'}, 'Location', 'northeast');
                grid(app.ResidualLossAxes, 'on');
                for g = 1:2
                    for s = 1:2
                        text(app.ResidualLossAxes, hb(s).XEndPoints(g), hb(s).YEndPoints(g), ...
                            sprintf(' %.1f', rmse_vals(g,s)), 'HorizontalAlignment', 'center', ...
                            'VerticalAlignment', 'bottom', 'FontSize', 11);
                    end
                end

                % --- 散佈圖: 上排 = NN 模型, 下排 = 最終匯出表 ---
                app.plot_scatter_fit(app.DirectIdAxes,   Id_u, Y_pred_nn(:,1), 'NN 模型: Id (A)',  [0 1 0]);
                app.plot_scatter_fit(app.DirectIqAxes,   Iq_u, Y_pred_nn(:,2), 'NN 模型: Iq (A)',  [0 1 0]);
                app.plot_scatter_fit(app.ResidualIdAxes, Id_u, id_lut,         '最終查表: Id (A)', [1 0 1]);
                app.plot_scatter_fit(app.ResidualIqAxes, Iq_u, iq_lut,         '最終查表: Iq (A)', [1 0 1]);

                % --- LUT 3D 曲面 ---
                cla(app.IdSurfaceAxes);
                surf(app.IdSurfaceAxes, FG, TG, Id_final, 'EdgeColor', 'none');
                colormap(app.IdSurfaceAxes, 'turbo'); colorbar(app.IdSurfaceAxes); view(app.IdSurfaceAxes, [-40, 30]);
                title(app.IdSurfaceAxes, 'Id LUT Surface (v1.4)');
                xlabel(app.IdSurfaceAxes, 'Flux command (Wb)'); ylabel(app.IdSurfaceAxes, 'Torque (Nm)'); zlabel(app.IdSurfaceAxes, 'Id (A)');
                cla(app.IqSurfaceAxes);
                surf(app.IqSurfaceAxes, FG, TG, Iq_final, 'EdgeColor', 'none');
                colormap(app.IqSurfaceAxes, 'turbo'); colorbar(app.IqSurfaceAxes); view(app.IqSurfaceAxes, [-40, 30]);
                title(app.IqSurfaceAxes, 'Iq LUT Surface (v1.4)');
                xlabel(app.IqSurfaceAxes, 'Flux command (Wb)'); ylabel(app.IqSurfaceAxes, 'Torque (Nm)'); zlabel(app.IqSurfaceAxes, 'Iq (A)');

                % --- Id-Iq 平面: LUT 格點 + 實驗點 + Imax 圓 + MTPA 軌跡 ---
                cla(app.IdIqPlaneDirectAxes); hold(app.IdIqPlaneDirectAxes, 'on');
                grid(app.IdIqPlaneDirectAxes, 'on'); box(app.IdIqPlaneDirectAxes, 'on');
                theta = linspace(pi/2, pi, 100);
                plot(app.IdIqPlaneDirectAxes, Imax*cos(theta), Imax*sin(theta), 'r--', 'LineWidth', 1.5, 'DisplayName', sprintf('Imax (%dA)', round(Imax)));
                plot(app.IdIqPlaneDirectAxes, Id_final(:), Iq_final(:), '.', 'Color', [0.65 0.65 0.65], 'MarkerSize', 3, 'DisplayName', 'LUT 格點');
                plot(app.IdIqPlaneDirectAxes, Id_u, Iq_u, 'b.', 'MarkerSize', 8, 'DisplayName', '實驗數據');
                plot(app.IdIqPlaneDirectAxes, Id_final(:,end), Iq_final(:,end), 'g-', 'LineWidth', 3, 'DisplayName', 'MTPA 軌跡 (最高磁通欄)');
                app.IdIqPlaneDirectAxes.XLim = [-(Imax+20), 20]; app.IdIqPlaneDirectAxes.YLim = [0, Imax+20];
                title(app.IdIqPlaneDirectAxes, 'Id-Iq 平面: LUT vs 實驗');
                xlabel(app.IdIqPlaneDirectAxes, 'Id (A)'); ylabel(app.IdIqPlaneDirectAxes, 'Iq (A)');
                legend(app.IdIqPlaneDirectAxes, 'TextColor', 'k', 'Color', 'none', 'EdgeColor', 'none', 'Location', 'northeast');

                % --- 查表電流誤差分布 (轉速 x 扭矩, 20Nm 分箱, 同格取最大) ---
                cla(app.IdIqPlaneResidualAxes, 'reset');
                spd_r = round(S_u/500)*500;
                trq_b = round(T_u/20)*20;
                us = unique(spd_r); ut = unique(trq_b);
                Z = nan(numel(ut), numel(us));
                for k = 1:n
                    r = find(ut == trq_b(k)); c = find(us == spd_r(k));
                    Z(r,c) = max(Z(r,c), abs(dId(k)));
                end
                h_img = imagesc(app.IdIqPlaneResidualAxes, 1:numel(us), 1:numel(ut), Z);
                set(app.IdIqPlaneResidualAxes, 'YDir', 'normal', 'Color', [0.94 0.94 0.94]);
                set(h_img, 'AlphaData', ~isnan(Z));
                colormap(app.IdIqPlaneResidualAxes, jet);
                clim(app.IdIqPlaneResidualAxes, [0, 30]);
                set(app.IdIqPlaneResidualAxes, 'XTick', 1:2:numel(us), 'XTickLabel', us(1:2:end), ...
                    'YTick', 1:2:numel(ut), 'YTickLabel', ut(1:2:end));
                xlabel(app.IdIqPlaneResidualAxes, '轉速 (RPM)'); ylabel(app.IdIqPlaneResidualAxes, '扭矩 (Nm)');
                title(app.IdIqPlaneResidualAxes, 'Id 電流誤差分布 (查表值 vs 實測值)');
                cb = colorbar(app.IdIqPlaneResidualAxes); cb.Label.String = 'Id 誤差 |查表-實測| (A)';

                % --- 文字報告 ---
                [~, worst_idx] = sort(abs(dId), 'descend');
                worst_lines = cell(min(5, n), 1);
                for w = 1:numel(worst_lines)
                    iw = worst_idx(w);
                    worst_lines{w} = sprintf('  T=%g, %g rpm, Flux=%.4f: Id 表%.1f/實%.1f (誤差 %.1f A), Iq 表%.1f/實%.1f', ...
                        T_u(iw), round(S_u(iw)), F_u(iw), id_lut(iw), Id_u(iw), dId(iw), iq_lut(iw), Iq_u(iw));
                end
                if ref_ok, ref_str = '已載入 (差值修正填補)'; else, ref_str = '未載入 (邊界延續填補)'; end
                report_text = [{
                    '============================================';
                    '   AI 產表報告 v1.4 (直接 T-Flux 模型)';
                    '============================================';
                    sprintf('訓練樣本: %d 點 (有效 %d 筆)', n, numel(T_e));
                    sprintf('實測有效電壓 V_eff = median(Flux*w_e) = %.1f V', V_eff);
                    sprintf('網格: %d torque x %d flux, 實驗涵蓋 %.1f%%', n_t, n_f, 100*mean(in_support(:)));
                    sprintf('ZEPT 參考表: %s', ref_str);
                    '';
                    '【最終查表 vs 實驗 (全部實測工作點)】';
                    sprintf('Id 電流誤差 (查表-實測): 平均 %.1f A, RMS %.1f A, 最大 %.1f A', mean(abs(dId)), rms(dId), max(abs(dId)));
                    sprintf('Iq 電流誤差 (查表-實測): 平均 %.1f A, RMS %.1f A, 最大 %.1f A', mean(abs(dIq)), rms(dIq), max(abs(dIq)));
                    '';
                    '【誤差最大的 5 個工作點】'};
                    worst_lines;
                    {'============================================'}];
                app.ReportTextArea.Value = report_text;

                %% ===== 11. 匯出 Excel (與 v1.3 相同格式: metadata 5 列 + 磁通遞減軸) =====
                default_name = 'AI_Generated_Flux_LUT.xlsx';
                [file, path] = uiputfile('*.xlsx', '請選擇 AI 生成查表 Excel 檔案儲存位置', default_name);
                if isequal(file, 0) || isequal(path, 0), return; end
                full_excel_path = fullfile(path, file);
                if isfile(full_excel_path)
                    try
                        fid = fopen(full_excel_path, 'a');
                        if fid == -1, error('File locked'); end
                        fclose(fid);
                        delete(full_excel_path);
                    catch
                        uialert(app.UIFigure, ...
                            sprintf('無法寫入檔案！\n\n檔案可能正在 Excel 中開啟。\n請先關閉該檔案或更換檔名後再試。'), ...
                            '檔案寫入失敗', 'Icon', 'warning');
                        app.StatusLabel.Text = '⚠️ 匯出中斷：檔案被開啟中';
                        app.StatusLabel.FontColor = [0.9, 0.5, 0];
                        return;
                    end
                end
                metadata = {
                    'Tool Version:', 'v1.4 (direct T-Flux model)', '', '';
                    'Pole Pairs (p):', p_pairs, 'Rs (Ohm):', app.RsEditField.Value;
                    'Flux (Wb):', app.FluxEditField.Value, 'Imax (A):', Imax;
                    'Vdc (V):', app.VdcEditField.Value, 'V_eff (V):', round(V_eff, 1);
                    '====================', '====================', '====================', '===================='
                    };
                flux_vec_export = fliplr(flux_vec);
                Id_export = round(fliplr(Id_final), 2);
                Iq_export = round(fliplr(Iq_final), 2);
                sheet_data = {'Id_Table', Id_export; 'Iq_Table', Iq_export};
                for sh = 1:2
                    writecell(metadata, full_excel_path, 'Sheet', sheet_data{sh,1}, 'Range', 'A1');
                    writecell({'(Trq\Flux)'}, full_excel_path, 'Sheet', sheet_data{sh,1}, 'Range', 'A6');
                    writematrix(flux_vec_export, full_excel_path, 'Sheet', sheet_data{sh,1}, 'Range', 'B6');
                    writematrix(torque_vec', full_excel_path, 'Sheet', sheet_data{sh,1}, 'Range', 'A7');
                    writematrix(sheet_data{sh,2}, full_excel_path, 'Sheet', sheet_data{sh,1}, 'Range', 'B7');
                end
                uialert(app.UIFigure, sprintf('AI 查表已成功匯出至：\n%s\n', full_excel_path), '匯出成功', 'Icon', 'success');
                app.StatusLabel.Text = '✅ v1.4 產表完成！';
                app.StatusLabel.FontColor = [0, 0.7, 0];
            catch ME
                app.StatusLabel.Text = '❌ 訓練失敗！';
                app.StatusLabel.FontColor = [1, 0, 0];
                uialert(app.UIFigure, ['運算過程發生錯誤: ', ME.message], '訓練錯誤', 'Icon', 'error');
            end
        end

        % Button pushed function: SelectCSVButton
        function SelectCSVButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.csv', '請選擇實驗數據 CSV 檔案');
            if isequal(filename, 0), return; end

            filepath = fullfile(pathname, filename);
            app.trainCSVPath = filepath;

            % 更新 Edit Field 顯示路徑
            if isprop(app, 'TrainCSVPathEditField')
                app.TrainCSVPathEditField.Value = filepath;
            end

            app.CSVLamp.Color = [0, 1, 0];
            app.CSVLampLabel.Text = ['已載入：', filename];
            app.CSVLampLabel.FontColor = [0, 0.7, 0];

            uialert(app.UIFigure, ['已成功選取數據檔案：', filename], '檔案載入成功', 'Icon', 'success');
        end

        % Button pushed function: ExcelButton
        function ExcelButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.xlsx', '選擇參考操作點表格 (Idq (Trq, Flux).xlsx)');
            if isequal(filename, 0), return; end

            try
                filepath = fullfile(pathname, filename);
                sheets = sheetnames(filepath);
                iq_sheet_idx = find(~cellfun(@isempty, regexpi(sheets, 'iq')), 1);
                if isempty(iq_sheet_idx)
                    sheet_target = sheets{1};
                else
                    sheet_target = sheets{iq_sheet_idx};
                end

                raw = readmatrix(filepath, 'Sheet', sheet_target);
                flux_ref = raw(1, 2:end);
                flux_ref = flux_ref(~isnan(flux_ref));
                trq_ref = raw(2:end, 1);
                trq_ref = trq_ref(~isnan(trq_ref));

                f_min = min(flux_ref); f_max = max(flux_ref);
                t_min = min(trq_ref);  t_max = max(trq_ref);

                app.FluxMinEditField.Value = f_min;
                app.FluxMaxEditField.Value = f_max;
                app.TorqueMinEditField.Value = t_min;
                app.TorqueMaxEditField.Value = t_max;

                % 更新 Edit Field 顯示路徑
                if isprop(app, 'ExcelRefPathEditField')
                    app.ExcelRefPathEditField.Value = filepath;
                end

                msg = sprintf('✅ 讀取成功！\n\n【參考範圍】\n磁通: %.4f ~ %.4f\n扭矩: %.1f ~ %.1f\n\n已自動填入介面，您可自行修改「間距」。', f_min, f_max, t_min, t_max);
                uialert(app.UIFigure, msg, '參考範圍', 'Icon', 'info');
            catch ME
                uialert(app.UIFigure, ['讀取失敗: ', ME.message], '錯誤', 'Icon', 'error');
            end
        end

        % Button pushed function: SelectV0CSVButton
        function SelectV0CSVButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.csv', '請選擇 V0 舊版數據 CSV 檔案');
            if isequal(filename, 0), return; end

            filepath = fullfile(pathname, filename);
            app.v0CSVPath = filepath;

            % 將完整檔案路徑寫入 Edit Field
            if isprop(app, 'V0PathEditField')
                app.V0PathEditField.Value = filepath;
            end

            app.V0Lamp.Color = [0, 0.8, 0];
            app.V0StatusLabel.Text = ['已載入 V0：', filename];
            app.V0StatusLabel.FontColor = [0, 0.6, 0];

            uialert(app.UIFigure, ['已載入 V0 舊版檔案：', filename], '檔案載入成功', 'Icon', 'success');
        end

        % Button pushed function: SelectV1CSVButton
        function SelectV1CSVButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.csv', '請選擇 V1 新版數據 CSV 檔案');
            if isequal(filename, 0), return; end

            filepath = fullfile(pathname, filename);
            app.v1CSVPath = filepath;

            % 將完整檔案路徑寫入 Edit Field
            if isprop(app, 'V1PathEditField')
                app.V1PathEditField.Value = filepath;
            end

            app.V1Lamp.Color = [0, 0.8, 0];
            app.V1StatusLabel.Text = ['已載入 V1：', filename];
            app.V1StatusLabel.FontColor = [0, 0.6, 0];

            uialert(app.UIFigure, ['已載入 V1 新版檔案：', filename], '檔案載入成功', 'Icon', 'success');
        end

        % Button pushed function: BackToMainFromAnalysisButton
        function BackToMainFromAnalysisButtonPushed(app, event)
            app.MainMenuPanel.Visible = 'on';
            app.ErrorAnalysisPanel.Visible = 'off';
        end

        % Button pushed function: GoToErrorAnalysisButton
        function GoToErrorAnalysisButtonPushed(app, event)
            app.MainMenuPanel.Visible = 'off';
            app.ErrorAnalysisPanel.Visible = 'on';
        end

        % Button pushed function: RunErrorAnalysisButton
        function RunErrorAnalysisButtonPushed(app, event)
            app.runTorqueErrorAnalysis();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainMenuPanel = uipanel(app.UIFigure);
            app.MainMenuPanel.Title = '主選單';
            app.GoToMotorButton = uibutton(app.MainMenuPanel, 'push');
            app.GoToMotorButton.ButtonPushedFcn = createCallbackFcn(app, @GoToMotorButtonPushed, true);
            app.GoToMotorButton.Text = '設定馬達參數';
            app.GoToConditionButton = uibutton(app.MainMenuPanel, 'push');
            app.GoToConditionButton.ButtonPushedFcn = createCallbackFcn(app, @GoToConditionButtonPushed, true);
            app.GoToConditionButton.Text = '載入工作資料';
            app.GoToOutputButton = uibutton(app.MainMenuPanel, 'push');
            app.GoToOutputButton.ButtonPushedFcn = createCallbackFcn(app, @GoToOutputButtonPushed, true);
            app.GoToOutputButton.Text = '輸出與 AI 分析';
            app.AIappv14Label = uilabel(app.MainMenuPanel);
            app.AIappv14Label.Text = 'AI馬達電流優化分析app v1.4';
            app.GoToErrorAnalysisButton = uibutton(app.MainMenuPanel, 'push');
            app.GoToErrorAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @GoToErrorAnalysisButtonPushed, true);
            app.GoToErrorAnalysisButton.Text = '誤差分析';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = '輸出結果';
            app.OutputPanel.Visible = 'off';
            app.BackToMainFromOutputButton = uibutton(app.OutputPanel, 'push');
            app.BackToMainFromOutputButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMainFromOutputButtonPushed, true);
            app.BackToMainFromOutputButton.Text = '返回主選單';
            app.TabGroup = uitabgroup(app.OutputPanel);
            app.DataDistTab = uitab(app.TabGroup);
            app.DataDistTab.Title = '資料分布';
            app.FluxHistAxes = uiaxes(app.DataDistTab);
            title(app.FluxHistAxes, '磁鏈分佈直方圖')
            xlabel(app.FluxHistAxes, 'Flux (Wb)')
            ylabel(app.FluxHistAxes, '資料筆數')
            zlabel(app.FluxHistAxes, 'Z')
            app.TorqueHistAxes = uiaxes(app.DataDistTab);
            title(app.TorqueHistAxes, '扭矩分佈直方圖')
            xlabel(app.TorqueHistAxes, 'Torque (N*m)')
            ylabel(app.TorqueHistAxes, '資料筆數')
            zlabel(app.TorqueHistAxes, 'Z')
            app.Label_7 = uilabel(app.DataDistTab);
            app.Label_7.Text = '資料統計';
            app.ReportTextArea = uitextarea(app.DataDistTab);
            app.ReportTextArea.Editable = 'off';
            app.ReportTextArea.Tooltip = {'這裡會顯示原本實驗資料經過預處理、訓練後的數據分析狀態，並以純文字方式顯示'};
            app.GenerateLUTButton = uibutton(app.DataDistTab, 'push');
            app.GenerateLUTButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateLUTButtonPushed, true);
            app.GenerateLUTButton.Text = '執行AI分析';
            app.StatusLabel = uilabel(app.DataDistTab);
            app.StatusLabel.Text = '等待執行...';
            app.DescriptionDataLabel = uilabel(app.DataDistTab);
            app.DescriptionDataLabel.Tooltip = {'下兩張圖呈現資料經處理後的柱狀分布，左圖橫軸為磁通、右圖橫軸為扭矩'};
            app.DescriptionDataLabel.Text = 'ⓘ';
            app.TrainingTrendTab = uitab(app.TabGroup);
            app.TrainingTrendTab.Title = '模型訓練趨勢';
            app.DirectLossAxes = uiaxes(app.TrainingTrendTab);
            title(app.DirectLossAxes, 'Direct Model Loss 曲線')
            xlabel(app.DirectLossAxes, 'Iteration')
            ylabel(app.DirectLossAxes, 'MSE (log)')
            zlabel(app.DirectLossAxes, 'Z')
            app.ResidualLossAxes = uiaxes(app.TrainingTrendTab);
            title(app.ResidualLossAxes, 'Residual Model Loss 曲線')
            xlabel(app.ResidualLossAxes, 'Iteration')
            ylabel(app.ResidualLossAxes, 'MSE (log)')
            zlabel(app.ResidualLossAxes, 'Z')
            app.DirectIdAxes = uiaxes(app.TrainingTrendTab);
            title(app.DirectIdAxes, 'Direct Model：Id (A)')
            xlabel(app.DirectIdAxes, 'True Id')
            ylabel(app.DirectIdAxes, 'Predicted Id')
            zlabel(app.DirectIdAxes, 'Z')
            app.DirectIqAxes = uiaxes(app.TrainingTrendTab);
            title(app.DirectIqAxes, 'Direct Model：Iq (A)')
            xlabel(app.DirectIqAxes, 'True Iq')
            ylabel(app.DirectIqAxes, 'Predicted Iq')
            zlabel(app.DirectIqAxes, 'Z')
            app.ResidualIdAxes = uiaxes(app.TrainingTrendTab);
            title(app.ResidualIdAxes, 'Residual Mode：Id (A)')
            xlabel(app.ResidualIdAxes, 'True Id')
            ylabel(app.ResidualIdAxes, 'Predicted Id')
            zlabel(app.ResidualIdAxes, 'Z')
            app.ResidualIqAxes = uiaxes(app.TrainingTrendTab);
            title(app.ResidualIqAxes, 'Residual Mode：Iq (A)')
            xlabel(app.ResidualIqAxes, 'True Iq')
            ylabel(app.ResidualIqAxes, 'Predicted Iq')
            zlabel(app.ResidualIqAxes, 'Z')
            app.DescriptionLossLabel = uilabel(app.TrainingTrendTab);
            app.DescriptionLossLabel.Tooltip = {'左圖【訓練收斂曲線】灰色為每次迭代的訓練 loss（正規化 MSE、log 尺度），綠色為固定驗證集 loss。'; '判讀：綠線下降後持平＝已收斂；綠灰兩線貼近＝無過擬合。灰線若有小幅跳動屬批次抽樣雜訊，以綠線為準。'; '右圖【模型預測精度】將模型誤差換算成安培（RMSE），藍色＝訓練集、橘色＝驗證集，分別顯示 Id 與 Iq。'; '判讀：驗證柱與訓練柱接近＝泛化正常；驗證柱明顯高於訓練柱（約 2 倍以上）＝過擬合，應減少訓練輪數或補充數據。'};
            app.DescriptionLossLabel.Text = 'ⓘ';
            app.DescriptionPredictLabel = uilabel(app.TrainingTrendTab);
            app.DescriptionPredictLabel.Tooltip = {'四張圖皆為「預測值 vs 實測值」散佈，橫軸為動力計實測電流，縱軸為預測，紅色虛線為 45 度理想線，點越貼近線越準。'; '上排（綠色）＝NN 模型的直接預測，反映模型本身的學習品質。'; '下排（粉紅）＝最終匯出查表在相同工作點的查表結果，已含參考表填補、表格校準與物理約束，代表控制器實際會拿到的電流。'; '判讀：下排與上排的差異來自網格離散化與後處理；Id 最負端（深弱磁高扭矩角點）的散開為已知的網格解析度極限，屬正常現象。'};
            app.DescriptionPredictLabel.Text = 'ⓘ';
            app.ResultOutputTab = uitab(app.TabGroup);
            app.ResultOutputTab.Title = '結果輸出';
            app.IdIqPlaneDirectAxes = uiaxes(app.ResultOutputTab);
            title(app.IdIqPlaneDirectAxes, 'Id-Iq Plane (Direct AI)')
            xlabel(app.IdIqPlaneDirectAxes, 'Id (A)')
            ylabel(app.IdIqPlaneDirectAxes, 'Iq (A)')
            zlabel(app.IdIqPlaneDirectAxes, 'Z')
            app.IdIqPlaneResidualAxes = uiaxes(app.ResultOutputTab);
            title(app.IdIqPlaneResidualAxes, 'Id-Iq Plane (Residual AI)')
            xlabel(app.IdIqPlaneResidualAxes, 'Id (A)')
            ylabel(app.IdIqPlaneResidualAxes, 'Iq (A)')
            zlabel(app.IdIqPlaneResidualAxes, 'Z')
            app.IdSurfaceAxes = uiaxes(app.ResultOutputTab);
            title(app.IdSurfaceAxes, 'Id LUT Surface')
            xlabel(app.IdSurfaceAxes, 'Id (A)')
            ylabel(app.IdSurfaceAxes, 'Iq (A)')
            zlabel(app.IdSurfaceAxes, 'Z')
            app.IqSurfaceAxes = uiaxes(app.ResultOutputTab);
            title(app.IqSurfaceAxes, 'Id LUT Surface')
            xlabel(app.IqSurfaceAxes, 'Id (A)')
            ylabel(app.IqSurfaceAxes, 'Iq (A)')
            zlabel(app.IqSurfaceAxes, 'Z')
            app.DescriptionMTPALabel = uilabel(app.ResultOutputTab);
            app.DescriptionMTPALabel.Tooltip = {'上半部分可以觀察模型預測出的電流組合點在MTPA圖上的分佈情形，以及最佳化曲線的結果，還有個工作分布下的電流預測值與原本誤差的熱力圖分布。'};
            app.DescriptionMTPALabel.Text = 'ⓘ';
            app.DescriptionLUTLabel = uilabel(app.ResultOutputTab);
            app.DescriptionLUTLabel.Tooltip = {'下半部分則是將匯出的查表以視覺化的方式呈現成3D曲面的形式'};
            app.DescriptionLUTLabel.Text = 'ⓘ';
            app.MotorPanel = uipanel(app.UIFigure);
            app.MotorPanel.Title = '馬達介面';
            app.MotorPanel.Visible = 'off';
            app.BackToMainFromMotorButton = uibutton(app.MotorPanel, 'push');
            app.BackToMainFromMotorButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMainFromMotorButtonPushed, true);
            app.BackToMainFromMotorButton.Text = '返回主選單';
            app.LoadLUTButton = uibutton(app.MotorPanel, 'push');
            app.LoadLUTButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLUTButtonPushed, true);
            app.LoadLUTButton.Tooltip = {'可以選擇馬達廠提供的Ld、Lq查表數據'};
            app.LoadLUTButton.Text = '載入動態電感表 (Ld/Lq LUT)';
            app.pEditFieldLabel = uilabel(app.MotorPanel);
            app.pEditFieldLabel.Text = '極對數 (p)';
            app.pEditField = uieditfield(app.MotorPanel, 'numeric');
            app.pEditField.Value = 4;
            app.RsEditFieldLabel = uilabel(app.MotorPanel);
            app.RsEditFieldLabel.Text = '定子電阻 (Rs)';
            app.RsEditField = uieditfield(app.MotorPanel, 'numeric');
            app.RsEditField.Value = 0.033947;
            app.dLdLabel = uilabel(app.MotorPanel);
            app.dLdLabel.Text = 'd軸電感 (Ld)';
            app.LdEditField = uieditfield(app.MotorPanel, 'numeric');
            app.LdEditField.Value = 0.000525;
            app.qLqEditFieldLabel = uilabel(app.MotorPanel);
            app.qLqEditFieldLabel.Text = 'q軸電感 (Lq)';
            app.LqEditField = uieditfield(app.MotorPanel, 'numeric');
            app.LqEditField.Value = 0.001126;
            app.FluxLabel = uilabel(app.MotorPanel);
            app.FluxLabel.Text = '永磁磁鏈 (Flux)';
            app.FluxEditField = uieditfield(app.MotorPanel, 'numeric');
            app.FluxEditField.Value = 0.14314;
            app.ImaxLabel = uilabel(app.MotorPanel);
            app.ImaxLabel.Text = '極限電流 (Imax)';
            app.ImaxEditField = uieditfield(app.MotorPanel, 'numeric');
            app.ImaxEditField.Value = 405;
            app.VdcEditFieldLabel = uilabel(app.MotorPanel);
            app.VdcEditFieldLabel.Text = '直流鏈電壓 (Vdc)';
            app.VdcEditField = uieditfield(app.MotorPanel, 'numeric');
            app.VdcEditField.Value = 664;
            app.LampLabel = uilabel(app.MotorPanel);
            app.LampLabel.Text = '尚未載入，使用固定電感';
            app.Lamp = uilamp(app.MotorPanel);
            app.Label_10 = uilabel(app.MotorPanel);
            app.Label_10.Text = '檔案路徑';
            app.LdLqPathEditField = uieditfield(app.MotorPanel, 'text');
            app.LdLqPathEditField.Editable = 'off';
            app.ConditionalPanel = uipanel(app.UIFigure);
            app.ConditionalPanel.Title = '工作條件';
            app.ConditionalPanel.Visible = 'off';
            app.BackToMainFromConditionButton = uibutton(app.ConditionalPanel, 'push');
            app.BackToMainFromConditionButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMainFromConditionButtonPushed, true);
            app.BackToMainFromConditionButton.Text = '返回主選單';
            app.SelectCSVButton = uibutton(app.ConditionalPanel, 'push');
            app.SelectCSVButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCSVButtonPushed, true);
            app.SelectCSVButton.Tooltip = {'選擇想作為訓練資料集的實驗數據，注意格式內容'};
            app.SelectCSVButton.Text = '選擇訓練資料 (CSV)';
            app.Label = uilabel(app.ConditionalPanel);
            app.Label.Text = '起始扭矩';
            app.TorqueMinEditField = uieditfield(app.ConditionalPanel, 'numeric');
            app.Label_2 = uilabel(app.ConditionalPanel);
            app.Label_2.Text = '結束扭矩';
            app.TorqueMaxEditField = uieditfield(app.ConditionalPanel, 'numeric');
            app.TorqueMaxEditField.Value = 300;
            app.Label_3 = uilabel(app.ConditionalPanel);
            app.Label_3.Text = '扭矩間距';
            app.TorqueStepEditField = uieditfield(app.ConditionalPanel, 'numeric');
            app.TorqueStepEditField.Value = 10;
            app.Label_4 = uilabel(app.ConditionalPanel);
            app.Label_4.Text = '磁通間距';
            app.FluxStepEditField = uieditfield(app.ConditionalPanel, 'numeric');
            app.FluxStepEditField.Value = 0.0079;
            app.Label_5 = uilabel(app.ConditionalPanel);
            app.Label_5.Text = '結束磁通';
            app.FluxMaxEditField = uieditfield(app.ConditionalPanel, 'numeric');
            app.FluxMaxEditField.Value = 0.2065;
            app.Label_6 = uilabel(app.ConditionalPanel);
            app.Label_6.Text = '起始磁通';
            app.FluxMinEditField = uieditfield(app.ConditionalPanel, 'numeric');
            app.MaxEpochsEditFieldLabel = uilabel(app.ConditionalPanel);
            app.MaxEpochsEditFieldLabel.Text = '訓練輪數 (MaxEpochs)';
            app.EpochsEditField = uieditfield(app.ConditionalPanel, 'numeric');
            app.EpochsEditField.Value = 2000;
            app.LearnRateEditFieldLabel = uilabel(app.ConditionalPanel);
            app.LearnRateEditFieldLabel.Text = '初始學習率 (LearnRate)';
            app.LREditField = uieditfield(app.ConditionalPanel, 'numeric');
            app.LREditField.Value = 0.002;
            app.MiniBatchSizeEditFieldLabel = uilabel(app.ConditionalPanel);
            app.MiniBatchSizeEditFieldLabel.Text = '批次大小 (MiniBatchSize)';
            app.BatchSizeEditField = uieditfield(app.ConditionalPanel, 'numeric');
            app.BatchSizeEditField.Value = 256;
            app.CSVLamp = uilamp(app.ConditionalPanel);
            app.CSVLampLabel = uilabel(app.ConditionalPanel);
            app.CSVLampLabel.Text = '請選擇實驗數據 CSV 檔案';
            app.ExcelButton = uibutton(app.ConditionalPanel, 'push');
            app.ExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExcelButtonPushed, true);
            app.ExcelButton.Tooltip = {'若有馬達廠提供之電流操作點表格(Trq,Flux)可以匯入，系統會自動抓取最大最小值並填入，若無也可自行手動調整'};
            app.ExcelButton.Text = '讀取參考表(Excel)範圍';
            app.Label_11 = uilabel(app.ConditionalPanel);
            app.Label_11.Text = '檔案路徑';
            app.ExcelRefPathEditField = uieditfield(app.ConditionalPanel, 'text');
            app.ExcelRefPathEditField.Editable = 'off';
            app.Label_12 = uilabel(app.ConditionalPanel);
            app.Label_12.Text = '檔案路徑';
            app.TrainCSVPathEditField = uieditfield(app.ConditionalPanel, 'text');
            app.TrainCSVPathEditField.Editable = 'off';
            app.ErrorAnalysisPanel = uipanel(app.UIFigure);
            app.ErrorAnalysisPanel.Title = '誤差分析';
            app.ErrorAnalysisPanel.Visible = 'off';
            app.AxesV0 = uiaxes(app.ErrorAnalysisPanel);
            title(app.AxesV0, 'Title')
            xlabel(app.AxesV0, 'X')
            ylabel(app.AxesV0, 'Y')
            zlabel(app.AxesV0, 'Z')
            app.AxesV1 = uiaxes(app.ErrorAnalysisPanel);
            title(app.AxesV1, 'Title')
            xlabel(app.AxesV1, 'X')
            ylabel(app.AxesV1, 'Y')
            zlabel(app.AxesV1, 'Z')
            app.BackToMainFromAnalysisButton = uibutton(app.ErrorAnalysisPanel, 'push');
            app.BackToMainFromAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMainFromAnalysisButtonPushed, true);
            app.BackToMainFromAnalysisButton.Text = '返回主選單';
            app.SelectV0CSVButton = uibutton(app.ErrorAnalysisPanel, 'push');
            app.SelectV0CSVButton.ButtonPushedFcn = createCallbackFcn(app, @SelectV0CSVButtonPushed, true);
            app.SelectV0CSVButton.Tooltip = {'選擇較早一次測試的實驗數據'};
            app.SelectV0CSVButton.Text = '載入舊數據(csv)';
            app.SelectV1CSVButton = uibutton(app.ErrorAnalysisPanel, 'push');
            app.SelectV1CSVButton.ButtonPushedFcn = createCallbackFcn(app, @SelectV1CSVButtonPushed, true);
            app.SelectV1CSVButton.Tooltip = {'選擇最近一次的實驗數據'};
            app.SelectV1CSVButton.Text = '載入新版數據(csv)';
            app.RunErrorAnalysisButton = uibutton(app.ErrorAnalysisPanel, 'push');
            app.RunErrorAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunErrorAnalysisButtonPushed, true);
            app.RunErrorAnalysisButton.Tooltip = {'將前兩次的數據做誤差比較'};
            app.RunErrorAnalysisButton.Text = '誤差分析';
            app.V1Lamp = uilamp(app.ErrorAnalysisPanel);
            app.V0Lamp = uilamp(app.ErrorAnalysisPanel);
            app.V0StatusLabel = uilabel(app.ErrorAnalysisPanel);
            app.V0StatusLabel.Text = '請選擇舊檔案';
            app.V1StatusLabel = uilabel(app.ErrorAnalysisPanel);
            app.V1StatusLabel.Text = '請選擇新檔案';
            app.DescriptionEALabel = uilabel(app.ErrorAnalysisPanel);
            app.DescriptionEALabel.Tooltip = {'呈現舊版與新版控制在各操作點下的扭矩誤差 (N*m)。顏色越偏紅代表該工作區間的絕對誤差越大。'};
            app.DescriptionEALabel.Text = 'ⓘ';
            app.Label_8 = uilabel(app.ErrorAnalysisPanel);
            app.Label_8.Text = '檔案路徑';
            app.V0PathEditField = uieditfield(app.ErrorAnalysisPanel, 'text');
            app.V0PathEditField.Editable = 'off';
            app.Label_9 = uilabel(app.ErrorAnalysisPanel);
            app.Label_9.Text = '檔案路徑';
            app.V1PathEditField = uieditfield(app.ErrorAnalysisPanel, 'text');
            app.V1PathEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_v1_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end